classdef appfunc_solv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        fileMenu                       matlab.ui.container.Menu
        importMenu                     matlab.ui.container.Menu
        refreshMenu                    matlab.ui.container.Menu
        saveMenu                       matlab.ui.container.Menu
        saveasMenu                     matlab.ui.container.Menu
        closeMenu                      matlab.ui.container.Menu
        helpMenu                       matlab.ui.container.Menu
        ShowTimeLabel                  matlab.ui.control.Label
        selectlossDropDown             matlab.ui.control.DropDown
        selectlossDropDownLabel        matlab.ui.control.Label
        VisulizationPanel              matlab.ui.container.Panel
        SolutionTableLabel             matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        lossAxes                       matlab.ui.control.UIAxes
        IterationmodelPanel            matlab.ui.container.Panel
        visualresultiterModelButton    matlab.ui.control.Button
        SORButton                      matlab.ui.control.Button
        GaussSeidelButton              matlab.ui.control.Button
        JacobiButton                   matlab.ui.control.Button
        GaussmethodPanel               matlab.ui.container.Panel
        visualresultButton             matlab.ui.control.Button
        freegaussButton                matlab.ui.control.Button
        all_gauss_Button               matlab.ui.control.Button
        col_gauss_Button               matlab.ui.control.Button
        ordergaussButton               matlab.ui.control.Button
        ThestatenowTextArea            matlab.ui.control.TextArea
        ThestatenowTextAreaLabel       matlab.ui.control.Label
        InputthematrixPanel            matlab.ui.container.Panel
        setmatrixButton                matlab.ui.control.Button
        inputmatrixButton              matlab.ui.control.Button
        calcondButton                  matlab.ui.control.Button
        Copyright2023curryqkaLabel     matlab.ui.control.Label
        LossTextArea                   matlab.ui.control.TextArea
        LossTextAreaLabel              matlab.ui.control.Label
        SolutionTextArea               matlab.ui.control.TextArea
        SolutionTextAreaLabel          matlab.ui.control.Label
        AccuratesolutionTextArea       matlab.ui.control.TextArea
        AccuratesolutionTextAreaLabel  matlab.ui.control.Label
        ThedimensionofthematrixEditField  matlab.ui.control.EditField
        ThedimensionofthematrixEditFieldLabel  matlab.ui.control.Label
        condTextArea                   matlab.ui.control.TextArea
        condTextAreaLabel              matlab.ui.control.Label
        matrixTextArea                 matlab.ui.control.TextArea
        matrixTextAreaLabel            matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end


    properties (Access = public)
        datetimer; % Description
    end

    properties (Access = private)
        % datetimer; % Description
    end

    methods (Access = public)
    
        function datetimer_handler(app)
            CT = clock; % current time
%             disp(['time now:', ...
%                 num2str(CT(1)), 'Year', num2str(CT(2), "%02d"), 'Month', ...
%                 num2str(CT(3), "%02d"), 'day', num2str(CT(4), "%02d"),': ', ...
%                 num2str(CT(5), "%02d"), ': ',num2str(CT(6), "%02.f")])
            
            app.ShowTimeLabel.Text = ['time now:', ...
                num2str(CT(1)), '-Year-', num2str(CT(2), "%02d"), '-Month-', ...
                num2str(CT(3), "%02d"), '-day-', num2str(CT(4), "%02d"),': ', ...
                num2str(CT(5), "%02d"), ': ',num2str(CT(6), "%02.f")];
            
%             set(app.ShowTimeLabel, "Text", ['time now:', ...
%                 num2str(CT(1)), 'Year', num2str(CT(2), "%02d"), 'Month', ...
%                 num2str(CT(3), "%02d"), 'day', num2str(CT(4), "%02d"),': ', ...
%                 num2str(CT(5), "%02d"), ': ',num2str(CT(6), "%02.f")]);
            
                
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % delete exist timer value
            delete(timerfind);
            % setting and start timer to showcase time
            app.datetimer = timer;
            % start timer code, delay 1s to use callback
            app.datetimer.StartDelay = 1;
            % use callback per 1second with timer
            app.datetimer.Period = 1;
            % timer mode
            app.datetimer.ExecutionMode = 'fixedRate';
            app.datetimer.BusyMode = 'queue';
            app.datetimer.TimerFcn = @(~, ~) app.datetimer_handler;
            start(app.datetimer);
        end

        % Button pushed function: setmatrixButton
        function setmatrixButtonPushed(app, event)
            % 接收矩阵维度输入框的值
            num_matrix = app.ThedimensionofthematrixEditField.Value;
            
            
            if str2double(num_matrix) == fix(str2double(num_matrix))
                % add the function path 
                addpath("实验3.1\");

                % 生成线性方程组的右端项和系数矩阵
                [A,b] = set_equation(str2double(num_matrix));

                % 导入显示矩阵的文本框组件
                app.matrixTextArea.Value = mat2str([A,b]);
                
                matrix = app.matrixTextArea.Value;
                matrix = eval(matrix{1});
                
                % add the function path
                addpath('实验3.1\');
                [X, cond_1, cond_2, cond_inf] = cal_cond(matrix(:,1:end-1),matrix(:,end));
                % 输出条件数
                app.condTextArea.Value = ['1-条件数:', num2str(cond_1),newline,...
                    '2-条件数:', num2str(cond_2), newline, ...
                    '无穷条件数:', num2str(cond_inf)];
                % 输出精确解
                app.AccuratesolutionTextArea.Value = mat2str(ones(length(X),1));
                
                % show now situation
                app.ThestatenowTextArea.Value = 'set matrix';
            else
                % if not input the dimension of the matrix
                msgbox('Please input the int num!', 'warning', 'warn');
                return;
            end
        end

        % Button pushed function: calcondButton
        function calcondButtonPushed(app, event)
            
            matrix = app.matrixTextArea.Value;
            
            % check whether the matrix is none
            % isempty(matrix)
            if isempty(matrix{1})
               msgbox('Please input the matrix first!', 'warning', 'warn');
               return;
            else
                matrix = eval(matrix{1});
                % disp(matrix);
                % add the function path
                addpath('实验3.1\');
                [X, cond_1, cond_2, cond_inf] = cal_cond(matrix(:,1:end-1),matrix(:,end));
                % 输出条件数
                app.condTextArea.Value = ['1-条件数:', num2str(cond_1),newline,...
                    '2-条件数:', num2str(cond_2), newline, ...
                    '无穷条件数:', num2str(cond_inf)];
                % 输出精确解
                app.AccuratesolutionTextArea.Value = mat2str(ones(length(X),1));
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'calculate the cond';
        end

        % Button pushed function: ordergaussButton
        function ordergaussButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            
            % check whether the matrix is none
            % isempty(matrix)
            if isempty(matrix{1})
               msgbox('Please input the matrix first!', 'warning', 'warn');
               return;
            else
                
                matrix = eval(matrix{1});
                A = matrix(:, 1:end-1);
                b = matrix(:, end);
                % add the function path
                addpath('实验3.1\');
                x = order_gauss(A,b);
                % add the solution to the table
                app.SolutionTextArea.Value = mat2str(x);
                app.UITable.Data = [];
                app.UITable.Data = [app.UITable.Data; x];
                % calculate the loss
                solution = app.AccuratesolutionTextArea.Value;
                solution = eval(solution{1});
                [loss1, ~, ~] = cal_loss(solution, x);
                % show the loss: 1-norm
                app.LossTextArea.Value = num2str(loss1);
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'The order guass';
        end

        % Button pushed function: col_gauss_Button
        function col_gauss_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            
             
            % check whether the matrix is none
            % isempty(matrix)
            if isempty(matrix{1})
               msgbox('Please input the matrix first!', 'warning', 'warn');
               return;
            else
                matrix = eval(matrix{1});
                A = matrix(:, 1:end-1);
                b = matrix(:, end);
                % add the function path
                addpath('实验3.1\');
                x = col_gauss(A,b);
                app.SolutionTextArea.Value = mat2str(x);
                % % add the solution to the table
                app.UITable.Data = [];
                app.UITable.Data = [app.UITable.Data; x];
                
                % calculate the loss
                solution = app.AccuratesolutionTextArea.Value;
                solution = eval(solution{1});
                [loss1, ~, ~] = cal_loss(solution, x);
                % show the loss: 1-norm
                app.LossTextArea.Value = num2str(loss1);
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'The column gauss';
        end

        % Button pushed function: all_gauss_Button
        function all_gauss_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
             
            % check whether the matrix is none
            % isempty(matrix)
            if isempty(matrix{1})
               msgbox('Please input the matrix first!', 'warning', 'warn');
               return;
            else
                matrix = eval(matrix{1});
                A = matrix(:, 1:end-1);
                b = matrix(:, end);
                % add the function path
                addpath('实验3.1\');
                x = all_gauss(A, b);
                app.SolutionTextArea.Value = mat2str(x);
                % % add the solution to the table
                app.UITable.Data = [];
                app.UITable.Data = [app.UITable.Data; x];
                % calculate the loss
                solution = app.AccuratesolutionTextArea.Value;
                solution = eval(solution{1});
                [loss1, ~, ~] = cal_loss(solution, x);
                % show the loss: 1-norm
                app.LossTextArea.Value = num2str(loss1);
            end
            
             % now situation
            app.ThestatenowTextArea.Value = 'The all guass';
        end

        % Button pushed function: freegaussButton
        function freegaussButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            
             
            % check whether the matrix is none
            % isempty(matrix)
            if isempty(matrix{1})
               msgbox('Please input the matrix first!', 'warning', 'warn');
               return;
            else
                matrix = eval(matrix{1});
                A = matrix(:, 1:end-1);
                b = matrix(:, end);
                % add the function path
                addpath('实验3.1\');
                x = free_gauss(A, b);
                app.SolutionTextArea.Value = mat2str(x);
                % % add the solution to the table
                app.UITable.Data = [];
                app.UITable.Data = [app.UITable.Data; x];
                % calculate the loss
                solution = app.AccuratesolutionTextArea.Value;
                solution = eval(solution{1});
                [loss1, ~, ~] = cal_loss(solution, x);
                % show the loss: 1-norm
                app.LossTextArea.Value = num2str(loss1);
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'The free gauss';
        end

        % Button pushed function: visualresultButton
        function visualresultButtonPushed(app, event)
            save_loss = [];
            matrix = app.matrixTextArea.Value;
             
            % check whether the matrix is none
            % isempty(matrix)
            if isempty(matrix{1})
               msgbox('Please input the matrix first!', 'warning', 'warn');
               return;
            else
                
                matrix = eval(matrix{1});
                A = matrix(:, 1:end-1);
                b = matrix(:, end);
                % add the function path
                addpath('实验3.1\');
                % calculate the loss
                solution = app.AccuratesolutionTextArea.Value;
                solution = eval(solution{1});
                x = order_gauss(A, b);
                [loss1, ~, loss_inf] = cal_loss(solution, x);
    
                save_loss = [save_loss; loss_inf];
    
                x = col_gauss(A, b);
                [loss1, ~, loss_inf] = cal_loss(solution, x);
    
                save_loss = [save_loss; loss_inf];
    
                x = all_gauss(A, b);
                [loss1, ~, loss_inf] = cal_loss(solution, x);
    
                save_loss = [save_loss; loss_inf];
    
                x = free_gauss(A, b);
                [loss1, ~, loss_inf] = cal_loss(solution, x);
    
                save_loss = [save_loss; loss_inf];
                bar( 1:4, save_loss,'parent', app.lossAxes)
                
                app.lossAxes.XLabel.String = '方法';
                app.lossAxes.YLabel.String = '误差';
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'The visualization of different gauss method';
        end

        % Button pushed function: visualresultiterModelButton
        function visualresultiterModelButtonPushed(app, event)
            % test example : given dim, use class itermodel
            addpath('./实验3.3')
            dim = app.ThedimensionofthematrixEditField.Value;
            % check whether the dim is none
            if isempty(dim)
               msgbox('Please input the dimension of matrix first!', 'warning', 'warn');
               return;
            else
                
                iterModel = itermodel(str2double(dim));
                %% matlab 要返回类才会更新
                iterModel = iterModel.set_matrix();
                
                %% 定义需要存储的数据
                save_loss_inf = [];
                save_x = [];
                %% LU分解
                [L, U] = iterModel.get_LU();
                [L, U] = iterModel.get_LU_check();
                disp('LU分解：')
                x = iterModel.gauss_method(L, U)
                [~, ~, loss_inf] = iterModel.cal_loss(x)
                
                save_loss_inf = [save_loss_inf; loss_inf];
                save_x = [save_x, x];
                
                %% J法
                rou_J = iterModel.cal_rou('J');
                % disp('jacobi：')
                [x, error_jacobi] = iterModel.jacobi_method();
                [~, ~, loss_inf] = iterModel.cal_loss(x);
                % save_loss_inf = [save_loss_inf; loss_inf];
                
                save_x = [save_x, x];
                
                %% GS法
                rou_GS = iterModel.cal_rou('G');
                % disp('高斯塞达尔：')
                [x, error_gauss_seidel] = iterModel.gs_method();
                [~, ~, loss_inf] = iterModel.cal_loss(x);
                
                save_loss_inf = [save_loss_inf; loss_inf];
                save_x = [save_x, x];
                %% SOR法
                rou_SOR = iterModel.cal_rou('S', 0.5);
                % disp('sor：')
                [x, error_sor] = iterModel.sor_method(0.5);
                [~, ~, loss_inf] = iterModel.cal_loss(x);
                % draw the loss fig
                if max(error_jacobi) > 100
                    iterModel.get_losscurve(error_gauss_seidel, 'GS',error_sor, 'SOR')
                else
                    iterModel.get_losscurve(error_jacobi, 'J', error_gauss_seidel, 'GS',error_sor,'SOR')
                end
                
                save_loss_inf = [save_loss_inf; loss_inf];
                save_x = [save_x, x];
                %% draw loss_inf
                
                lw = 1.5;
                t = 1:length(save_loss_inf);
                bar(t, save_loss_inf, 'parent', app.lossAxes, 'LineWidth', 1.5);
                hold on
                
                for i = 1:length(save_loss_inf)
                    
                    text(t(i),save_loss_inf(i),num2str(save_loss_inf(i),'%g%'),...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom', 'Parent', app.lossAxes)
                 
                end
                
                ax = app.lossAxes;
                ax.LineWidth = 1.5;
                ax.FontName = '微软雅黑';
                ax.FontSize = 14;
                grid on
                set(gca,'GridLineStyle','--')
                
                ax.XTick = 1:3;
                ax.XTickLabel = {'LU分解','GS法','SOR法'};
                % set(gca, 'XTick', 1:3, 'XTickLabel',{'LU分解','GS法','SOR法'})
                app.lossAxes.XLabel.String = '不同的方法';
                app.lossAxes.YLabel.String = '误差';
                
                legend (ax, ['维度',num2str(dim)]);
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'The visualization of different iteration model';
        end

        % Button pushed function: JacobiButton
        function JacobiButtonPushed(app, event)
            % test example : given dim, use class itermodel
            addpath('./实验3.3')
            dim = app.ThedimensionofthematrixEditField.Value;
            % check whether the dim is none
            if isempty(dim)
               msgbox('Please input the dimension of matrix first!', 'warning', 'warn');
               return;
            else
                
                iterModel = itermodel(str2double(dim));
                %% matlab 要返回类才会更新
                iterModel = iterModel.set_matrix();
                
                
                %% J法
                rou_J = iterModel.cal_rou('J');
                % disp('jacobi：')
                [x, error_jacobi] = iterModel.jacobi_method();
                [~, ~, loss_inf] = iterModel.cal_loss(x);
                % save_loss_inf = [save_loss_inf; loss_inf];
                app.SolutionTextArea.Value = mat2str(x);
                
                % show the loss: 1-norm
                app.LossTextArea.Value = num2str(loss_inf);
                
                % % add the solution to the table
                app.UITable.Data = [];
                app.UITable.Data = [app.UITable.Data; x];
                
                % DRAW 
                t = 1:length(error_jacobi);
                plot(t, error_jacobi, 'parent', app.lossAxes, 'LineWidth', 1.5);
                ax = app.lossAxes;
                ax.LineWidth = 1.5;
                ax.FontName = '微软雅黑';
                ax.FontSize = 14;
                grid on
                set(gca,'GridLineStyle','--')
                legend('The error of Jacobi method')
                
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'Jacobi method';
        end

        % Button pushed function: GaussSeidelButton
        function GaussSeidelButtonPushed(app, event)
            % test example : given dim, use class itermodel
            addpath('./实验3.3')
            dim = app.ThedimensionofthematrixEditField.Value;
            
            % check whether the dim is none
            if isempty(dim)
               msgbox('Please input the dimension of matrix first!', 'warning', 'warn');
            else
                
                iterModel = itermodel(str2double(dim));
                %% matlab 要返回类才会更新
                iterModel = iterModel.set_matrix();
                %% GS法
                rou_GS = iterModel.cal_rou('G');
                % disp('高斯塞达尔：')
                [x, error_gauss_seidel] = iterModel.gs_method();
                [~, ~, loss_inf] = iterModel.cal_loss(x);
                
                app.SolutionTextArea.Value = mat2str(x);
                
                % show the loss: 1-norm
                app.LossTextArea.Value = num2str(loss_inf);
                
                % % add the solution to the table
                app.UITable.Data = [];
                app.UITable.Data = [app.UITable.Data; x];
                
                % draw the loss curve
                t = 1:length(error_gauss_seidel);
                plot(t, error_gauss_seidel, 'parent', app.lossAxes, 'LineWidth', 1.5);
                ax = app.lossAxes;
                ax.LineWidth = 1.5;
                ax.FontName = '微软雅黑';
                ax.FontSize = 14;
                grid on
                set(gca,'GridLineStyle','--')
                legend('The error of GaussSeidel method')
            end
            % now situation
            app.ThestatenowTextArea.Value = 'GaussSeidel method';
        end

        % Button pushed function: SORButton
        function SORButtonPushed(app, event)
            % test example : given dim, use class itermodel
            addpath('./实验3.3')
            dim = app.ThedimensionofthematrixEditField.Value;
            %dim
            if isempty(dim)
               msgbox('Please input the dimension of matrix first!', 'warning', 'warn');
               return;
            else
                iterModel = itermodel(str2double(dim));
                %% matlab 要返回类才会更新
                iterModel = iterModel.set_matrix();
                %% SOR法
                % inputdlg
                prompt = {'The sor parameter:'};
                dlgtitle = 'Input';
                fieldsize = [1 20];
                definput = {'0.5'};
                answer = inputdlg(prompt, dlgtitle, fieldsize, definput);
                omega = str2double(answer{1});
                % disp(answer)
                rou_SOR = iterModel.cal_rou('S', omega);
                % disp('sor：')
                [x, error_sor] = iterModel.sor_method(omega);
                [~, ~, loss_inf] = iterModel.cal_loss(x);
                
                app.SolutionTextArea.Value = mat2str(x);
                
                % show the loss: 1-norm
                app.LossTextArea.Value = num2str(loss_inf);
                
                % % add the solution to the table
                app.UITable.Data = [];
                app.UITable.Data = [app.UITable.Data; x];
                
                % draw the loss curve
                t = 1:length(error_sor);
                plot(t, error_sor, 'parent', app.lossAxes, 'LineWidth', 1.5);
                ax = app.lossAxes;
                ax.LineWidth = 1.5;
                ax.FontName = '微软雅黑';
                ax.FontSize = 14;
                grid on
                set(gca,'GridLineStyle','--')
                legend('The error of SOR method')
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'SOR method';
        end

        % Menu selected function: closeMenu
        function closeMenuSelected(app, event)
            choice = questdlg("Sure closed?", "close", "YES", "NO", "NO");
            switch choice
                case "YES"
                    close(app.UIFigure);
                    return;
                case "NO"
                    return;
            end
        end

        % Button pushed function: inputmatrixButton
        function inputmatrixButtonPushed(app, event)
            % inputdlg
            prompt = {'The matrix(A):', 'The bias array(b):'};
            dlgtitle = 'Input';
            fieldsize = [1,50];
            % definput = {''};
            answer = inputdlg(prompt, dlgtitle, fieldsize);
            if isempty(answer)
                msgbox('The matrix did not input!', 'warning', 'warn');
            else
                A = str2num(answer{1});
                b = str2num(answer{2});
                app.matrixTextArea.Value = mat2str([A,b]);
            end
            
            % now situation
            app.ThestatenowTextArea.Value = 'Input the matrix...';
        end

        % Value changed function: selectlossDropDown
        function selectlossDropDownValueChanged(app, event)
            
            % add the function path
            addpath('实验3.1\');
            value = app.selectlossDropDown.Value;
            
            AccSolution = app.AccuratesolutionTextArea.Value;
            solution = app.SolutionTextArea.Value;
            
            % check whether the AccSolution solution is empty
            if isempty(AccSolution{1}) || isempty(solution{1})
               msgbox('Please calculate the solution first!', 'warning', 'warn');
               return;
            end
            AccSolution = eval(AccSolution{1});
            solution = eval(solution{1});
            switch value
                case "1-norm"
                    
                    [loss1, ~, ~] = cal_loss(AccSolution, solution);
                    % show the loss: 1-norm
                    app.LossTextArea.Value = num2str(['The 1-norm:',loss1]);
                    
                    % now situation
                    app.ThestatenowTextArea.Value = 'Select 1-norm as loss metric';
                    
                case "2-norm"
                   
                    [~, loss2, ~] = cal_loss(AccSolution, solution);
                    % show the loss: 2-norm
                    app.LossTextArea.Value = num2str(['The 2-norm:',loss2]);
                    
                    % now situation
                    app.ThestatenowTextArea.Value = 'Select 2-norm as loss metric';
                case "3-norm"
                  
                    [~, ~, loss_inf] = cal_loss(AccSolution, solution);
                    % show the loss: inf-norm
                    app.LossTextArea.Value = num2str(['The inf-norm:', loss_inf]);
                    
                    % now situation
                    app.ThestatenowTextArea.Value = 'Select inf-norm as loss metric';       
            end
        end

        % Menu selected function: importMenu
        function importMenuSelected(app, event)
            % select your file
            [file, path] = uigetfile( {'*.txt'; '*.csv';'*.mat';'*.xlsx';'*.xls' }, 'Select Data File');
            % [file, path] = uigetfile( {'*.csv';'*.mat';'*.xlsx' }, 'Select Data File');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                disp(['User selected', fullfile(path, file)])
                %file_name = [path, file];
                file_name = fullfile(path, file);
                % disp(file_name)
                % disp(path)
                global input_matrix
                    
%                 input_matrix = readtable(file_name);
%                 table2array(input_matrix)
                input_matrix = xlsread(file_name);                
                app.matrixTextArea.Value = mat2str(input_matrix);
                
                % now situation
                app.ThestatenowTextArea.Value = 'Import the matrix data...';
                    
            end
        end

        % Menu selected function: refreshMenu
        function refreshMenuSelected(app, event)
            app.ThedimensionofthematrixEditField.Value = '';
            app.matrixTextArea.Value = '';
            app.condTextArea.Value = '';
            app.AccuratesolutionTextArea.Value = '';
            app.SolutionTextArea.Value = '';
            app.LossTextArea.Value = '';
            app.ThestatenowTextArea.Value = '';
            app.selectlossDropDown.Value = '1-norm';
            app.UITable.Data = [];
            cla(app.lossAxes);
            
            % now situation
            app.ThestatenowTextArea.Value = 'Refresh now';
        end

        % Menu selected function: saveMenu
        function saveMenuSelected(app, event)
            % inputdlg
            prompt = {'The save file name:'};
            dlgtitle = 'Input';
            fieldsize = [1 20];
            definput = {'data.xls'};
            answer = inputdlg(prompt, dlgtitle, fieldsize, definput);
            if isempty(answer)
                msgbox('The file name did not input!', 'warning', 'warn');
                return;
            else
                if isempty(app.matrixTextArea.Value)
                    msgbox('The file name did not input!', 'warning', 'warn');
                    return;
                else
                    writecell(app.matrixTextArea.Value, answer{1}, 'Sheet', 1);
                    writecell(app.condTextArea.Value, answer{1}, 'Sheet', 2);
                    writecell(app.AccuratesolutionTextArea.Value, answer{1}, 'Sheet', 3);
                    writecell(app.SolutionTextArea.Value, answer{1}, 'Sheet', 4);
                    writecell(app.LossTextArea.Value, answer{1}, 'Sheet', 5);
                end
                
%                 A = str2num(answer{1});
%                 b = str2num(answer{2});
%                 app.matrixTextArea.Value = mat2str([A,b]);
            end
            
        end

        % Menu selected function: saveasMenu
        function saveasMenuSelected(app, event)
            % open file dlg
            [file, path] = uiputfile('*.mat', 'Save data as');
            if isequal(file, '') || isequal(path, '')
                % cancel save
                return;
            end
            % data saver
            data = app.AccuratesolutionTextArea.Value;
            save(fullfile(path, file), 'data');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fileMenu = uimenu(app.UIFigure);
            app.fileMenu.Text = 'file';
            app.importMenu = uimenu(app.fileMenu);
            app.importMenu.MenuSelectedFcn = createCallbackFcn(app, @importMenuSelected, true);
            app.importMenu.Text = 'import';
            app.refreshMenu = uimenu(app.fileMenu);
            app.refreshMenu.MenuSelectedFcn = createCallbackFcn(app, @refreshMenuSelected, true);
            app.refreshMenu.Text = 'refresh';
            app.saveMenu = uimenu(app.fileMenu);
            app.saveMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMenuSelected, true);
            app.saveMenu.Text = 'save';
            app.saveasMenu = uimenu(app.fileMenu);
            app.saveasMenu.MenuSelectedFcn = createCallbackFcn(app, @saveasMenuSelected, true);
            app.saveasMenu.Text = 'save as';
            app.closeMenu = uimenu(app.fileMenu);
            app.closeMenu.MenuSelectedFcn = createCallbackFcn(app, @closeMenuSelected, true);
            app.closeMenu.Text = 'close';
            app.helpMenu = uimenu(app.UIFigure);
            app.helpMenu.Text = 'help';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '线性方程组求解器';
            app.matrixTextAreaLabel = uilabel(app.UIFigure);
            app.matrixTextAreaLabel.Text = 'matrix';
            app.matrixTextArea = uitextarea(app.UIFigure);
            app.condTextAreaLabel = uilabel(app.UIFigure);
            app.condTextAreaLabel.Text = 'cond';
            app.condTextArea = uitextarea(app.UIFigure);
            app.ThedimensionofthematrixEditFieldLabel = uilabel(app.UIFigure);
            app.ThedimensionofthematrixEditFieldLabel.Text = 'The dimension of the matrix';
            app.ThedimensionofthematrixEditField = uieditfield(app.UIFigure, 'text');
            app.AccuratesolutionTextAreaLabel = uilabel(app.UIFigure);
            app.AccuratesolutionTextAreaLabel.Text = 'Accurate solution';
            app.AccuratesolutionTextArea = uitextarea(app.UIFigure);
            app.SolutionTextAreaLabel = uilabel(app.UIFigure);
            app.SolutionTextAreaLabel.Text = ' Solution';
            app.SolutionTextArea = uitextarea(app.UIFigure);
            app.LossTextAreaLabel = uilabel(app.UIFigure);
            app.LossTextAreaLabel.Text = 'Loss';
            app.LossTextArea = uitextarea(app.UIFigure);
            app.Copyright2023curryqkaLabel = uilabel(app.UIFigure);
            app.Copyright2023curryqkaLabel.Text = 'Copyright © 2023 curryqka ';
            app.InputthematrixPanel = uipanel(app.UIFigure);
            app.InputthematrixPanel.Title = 'Input the matrix';
            app.calcondButton = uibutton(app.InputthematrixPanel, 'push');
            app.calcondButton.ButtonPushedFcn = createCallbackFcn(app, @calcondButtonPushed, true);
            app.calcondButton.Text = 'cal-cond';
            app.inputmatrixButton = uibutton(app.InputthematrixPanel, 'push');
            app.inputmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @inputmatrixButtonPushed, true);
            app.inputmatrixButton.Text = 'input-matrix';
            app.setmatrixButton = uibutton(app.InputthematrixPanel, 'push');
            app.setmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @setmatrixButtonPushed, true);
            app.setmatrixButton.Text = 'set-matrix';
            app.ThestatenowTextAreaLabel = uilabel(app.UIFigure);
            app.ThestatenowTextAreaLabel.Text = 'The state now';
            app.ThestatenowTextArea = uitextarea(app.UIFigure);
            app.GaussmethodPanel = uipanel(app.UIFigure);
            app.GaussmethodPanel.Title = 'Gauss method';
            app.ordergaussButton = uibutton(app.GaussmethodPanel, 'push');
            app.ordergaussButton.ButtonPushedFcn = createCallbackFcn(app, @ordergaussButtonPushed, true);
            app.ordergaussButton.Text = 'order-gauss';
            app.col_gauss_Button = uibutton(app.GaussmethodPanel, 'push');
            app.col_gauss_Button.ButtonPushedFcn = createCallbackFcn(app, @col_gauss_ButtonPushed, true);
            app.col_gauss_Button.Text = 'col_gauss_Button';
            app.all_gauss_Button = uibutton(app.GaussmethodPanel, 'push');
            app.all_gauss_Button.ButtonPushedFcn = createCallbackFcn(app, @all_gauss_ButtonPushed, true);
            app.all_gauss_Button.Text = 'all_gauss_Button';
            app.freegaussButton = uibutton(app.GaussmethodPanel, 'push');
            app.freegaussButton.ButtonPushedFcn = createCallbackFcn(app, @freegaussButtonPushed, true);
            app.freegaussButton.Text = 'free-gauss';
            app.visualresultButton = uibutton(app.GaussmethodPanel, 'push');
            app.visualresultButton.ButtonPushedFcn = createCallbackFcn(app, @visualresultButtonPushed, true);
            app.visualresultButton.Text = 'visual-result';
            app.IterationmodelPanel = uipanel(app.UIFigure);
            app.IterationmodelPanel.Title = 'Iteration model';
            app.JacobiButton = uibutton(app.IterationmodelPanel, 'push');
            app.JacobiButton.ButtonPushedFcn = createCallbackFcn(app, @JacobiButtonPushed, true);
            app.JacobiButton.Text = 'Jacobi';
            app.GaussSeidelButton = uibutton(app.IterationmodelPanel, 'push');
            app.GaussSeidelButton.ButtonPushedFcn = createCallbackFcn(app, @GaussSeidelButtonPushed, true);
            app.GaussSeidelButton.Text = 'Gauss-Seidel';
            app.SORButton = uibutton(app.IterationmodelPanel, 'push');
            app.SORButton.ButtonPushedFcn = createCallbackFcn(app, @SORButtonPushed, true);
            app.SORButton.Text = 'SOR';
            app.visualresultiterModelButton = uibutton(app.IterationmodelPanel, 'push');
            app.visualresultiterModelButton.ButtonPushedFcn = createCallbackFcn(app, @visualresultiterModelButtonPushed, true);
            app.visualresultiterModelButton.Text = 'visual-result-iterModel';
            app.VisulizationPanel = uipanel(app.UIFigure);
            app.VisulizationPanel.Title = 'Visulization';
            app.lossAxes = uiaxes(app.VisulizationPanel);
            title(app.lossAxes, 'Title')
            xlabel(app.lossAxes, 'X')
            ylabel(app.lossAxes, 'Y')
            zlabel(app.lossAxes, 'Z')
            app.UITable = uitable(app.VisulizationPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SolutionTableLabel = uilabel(app.VisulizationPanel);
            app.SolutionTableLabel.Text = 'Solution Table';
            app.selectlossDropDownLabel = uilabel(app.UIFigure);
            app.selectlossDropDownLabel.Text = 'select loss';
            app.selectlossDropDown = uidropdown(app.UIFigure);
            app.selectlossDropDown.Items = {'1-norm', '2-norm', 'infinite-norm'};
            app.selectlossDropDown.ValueChangedFcn = createCallbackFcn(app, @selectlossDropDownValueChanged, true);
            app.selectlossDropDown.Value = '1-norm';
            app.ShowTimeLabel = uilabel(app.UIFigure);
            app.ShowTimeLabel.Text = 'ShowTimeLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appfunc_solv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end